classdef Crag_and_Coffee_Peak_Flow_new_Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ShowPrimeConversionWindowsCheckBox  matlab.ui.control.CheckBox
        CragCoffeePeakFlowDashboardLabel  matlab.ui.control.Label
        RecommendationLabel   matlab.ui.control.Label
        PlotHeatmapButton     matlab.ui.control.Button
        PersonaDropDown       matlab.ui.control.DropDown
        PersonaDropDownLabel  matlab.ui.control.Label
        HeatmapPanel          matlab.ui.container.Panel
    end

    
    properties (Access = private)
        data % access dataset
        HeatmapObj % to handle heatmap
        conversionMatrix
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % load dataset
            app.data = readtable("crowdedness_at_the_campus_gym.csv", ...
                VariableNamingRule="preserve");

            %prepare columns
            app.data.Hour = app.data.hour;
            app.data.Weekday = app.data.day_of_week;

            % **** Simulated Social data ****
            app.data.social_engagement = randi([5 100], height(app.data), 1);

            % **** Normalization ****
            trafficNorm = normalize(app.data.number_people, 'range');
            socialNorm = normalize(app.data.social_engagement, 'range');

            % **** Conversion Score ****
            app.data.conversionScore = trafficNorm .* (1 - socialNorm);

            % **** Build Conversion MATRIX (7x24) ****

            app.conversionMatrix = NaN(7,24);

            for d = 1:7
                for h = 0:23
                    idx = (app.data.Weekday == d) & (app.data.Hour == h);
                    if any(idx)
                        app.conversionMatrix(d, h+1) = mean(app.data.conversionScore(idx));
                    end
                end
            end
        end

        % Button pushed function: PlotHeatmapButton
        function PlotHeatmapButtonPushed(app, event)
            d = app.data;

            % **** Persona Filters ****

            switch app.PersonaDropDown.Value
                case 'Early Birds'
                    d = d(d.Hour < 12, :);
                case 'After Work'
                    d = d(d.Hour >= 17, :);
                case 'Weekend'
                    d = d(d.is_weekend == 1, :);
            end

            % **** Choose Data Type ****
            if app.ShowPrimeConversionWindowsCheckBox.Value
                heatMatrix = app.conversionMatrix;
            else
                heatMatrix = NaN(7,24);

                for day = 1:7
                    for hour = 0:23
                        idx = (d.Weekday == day) & (d.Hour == hour);
                        if any(idx)
                            heatMatrix(day, hour+1) = mean(d.number_people(idx));
                        end
                    end
                end
            end

            %labels
            days = ["Sun", "Mon","Tue","Wed","Thu","Fri","Sat"];
            hours = string(0:23);

            % **** Find Top 5 Windows ****
            [topVals, idxTop] = maxk(heatMatrix(:), 5);
            [row, col] = ind2sub(size(heatMatrix), idxTop);

            textOut  = "Top Windows:\n";

            for i = 1:5
                textOut = textOut + sprintf("%s %02d:00 (%.2f)\n", ...
                    days(row(i)), col(i)-1,  topVals(i));
            end

            % **** Recommendation Text ****

            textOut = "Top Windows: \n";
            for i = 1:5
                textOut = textOut + sprintf("%s %02d:00\n", ...
                    days(row(i)), col(i)-1);
            end

            app.RecommendationLabel.Text = textOut;

            % create heatmap
            app.HeatmapObj = heatmap(app.HeatmapPanel, hours, days, heatMatrix);

            % **** Title ****

            if app.ShowPrimeConversionWindowsCheckBox.Value
                app.HeatmapObj.Title = "Prime Conversion Windows";
            else
                app.HeatmapObj.Title = "Gym Occupancy Heatmap";
            end

            app.HeatmapObj.XLabel = "Hour of Day";
            app.HeatmapObj.YLabel = "Day of Week";

            % **** Style ****

            app.HeatmapObj.MissingDataColor = [0.2 0.2 0.2];
            app.HeatmapObj.Colormap = parula;

            app.RecommendationLabel.HorizontalAlignment = 'left';
            app.RecommendationLabel.WordWrap = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HeatmapPanel = uipanel(app.UIFigure);
            app.HeatmapPanel.Title = 'Heatmap Panel';
            app.PersonaDropDownLabel = uilabel(app.UIFigure);
            app.PersonaDropDownLabel.Text = 'Persona Drop Down';
            app.PersonaDropDown = uidropdown(app.UIFigure);
            app.PersonaDropDown.Items = {'All', 'Early Birds', 'After Work', 'Weekend'};
            app.PersonaDropDown.Value = 'All';
            app.PlotHeatmapButton = uibutton(app.UIFigure, 'push');
            app.PlotHeatmapButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHeatmapButtonPushed, true);
            app.PlotHeatmapButton.Text = 'Plot Heatmap';
            app.RecommendationLabel = uilabel(app.UIFigure);
            app.RecommendationLabel.Text = 'RecommendationLabel';
            app.CragCoffeePeakFlowDashboardLabel = uilabel(app.UIFigure);
            app.CragCoffeePeakFlowDashboardLabel.Text = '  Crag & Coffee Peak-Flow Dashboard ';
            app.ShowPrimeConversionWindowsCheckBox = uicheckbox(app.UIFigure);
            app.ShowPrimeConversionWindowsCheckBox.Text = 'Show Prime Conversion Windows';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crag_and_Coffee_Peak_Flow_new_Dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end